classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        MorphOpen               matlab.ui.control.Button
        MorphTest               matlab.ui.control.Button
        LabelDropDown           matlab.ui.control.Label
        MorphStrelType          matlab.ui.control.DropDown
        LabelDropDown2          matlab.ui.control.Label
        MorphType               matlab.ui.control.DropDown
        LabelSlider             matlab.ui.control.Label
        MorphSlider             matlab.ui.control.Slider
        MorphSave               matlab.ui.control.Button
        Tab2                    matlab.ui.container.Tab
        LabelSlider2            matlab.ui.control.Label
        BinarizeSlider          matlab.ui.control.Slider
        BinarizeTest            matlab.ui.control.Button
        BinarizeOpen            matlab.ui.control.Button
        BinarizeSave            matlab.ui.control.Button
        EdgesTab                matlab.ui.container.Tab
        TypeDropDownLabel       matlab.ui.control.Label
        EdgeType                matlab.ui.control.DropDown
        EdgeOpen                matlab.ui.control.Button
        ThresholdSliderLabel    matlab.ui.control.Label
        EdgeSlider              matlab.ui.control.Slider
        SigmaHocrossLabel       matlab.ui.control.Label
        EdgeSigma               matlab.ui.control.NumericEditField
        EdgeTest                matlab.ui.control.Button
        EdgeSave                matlab.ui.control.Button
        DirectionDropDownLabel  matlab.ui.control.Label
        EdgeDir                 matlab.ui.control.DropDown
        EdgeCustomName          matlab.ui.control.CheckBox
        EditFieldLabel          matlab.ui.control.Label
        EdgeOutputName          matlab.ui.control.EditField
    end


    properties (Access = private)
        orig_image % Description
        strel_method % Description
        is_changable
        morph_img % Description
        orig_filename


        % binary
        ThreshInputImage 
        ThreshInputFilename
        ThreshOutputImage
        
        
        % edges

        EdgeInputImage
        EdgeInputFilename
        EdgeOutputImage
        
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: MorphOpen
        function MorphOpenButtonPushed(app, event)
        [app.orig_filename,orig_filepath] = uigetfile
        
        app.orig_image = imread(app.orig_filename)
        figure('Name','Original Image'),imshow(app.orig_image)      
        end

        % Button pushed function: MorphTest
        function MorphTestButtonPushed(app, event)
        val = app.MorphStrelType.Value
        se = []
        slider_val = app.MorphSlider.Value
       
        
        if strcmp(val,'diamond')
           se = strel('diamond',floor(slider_val)) 
        elseif strcmp(val,'disk')
           se = strel('disk',floor(slider_val))
        
        elseif strcmp(val,'rectangle')
            
           se = strel('rectangle',[floor(slider_val) floor(slider_val)])
        end
        
        m_op_type = app.MorphType.Value
        if strcmp(m_op_type,'Erosion')
            app.morph_img = imerode(app.orig_image,se)
        elseif strcmp(m_op_type,'Dilation')
            app.morph_img = imdilate (app.orig_image,se)
        end
        
        
        imshow(app.morph_img)
      
        
        end

        % Value changing function: MorphSlider
        function MorphSliderValueChanging(app, event)
            
            
        end

        % Button pushed function: binary
        function binaryButtonPushed(app, event)
            
        end

        % Button pushed function: BinarizeOpen
        function BinarizeOpenButtonPushed(app, event)
            
            [app.ThreshInputFilename,filepath] = uigetfile
            app.ThreshInputImage = imread(app.ThreshInputFilename)
            figure('Name','InputforThresholding'),imshow(app.ThreshInputImage)
            
            
        end

        % Value changed function: BinarizeSlider
        function BinarizeSliderValueChanged(app, event)
            
        end

        % Button pushed function: BinarizeTest
        function BinarizeTestButtonPushed(app, event)
            value = app.BinarizeSlider.Value;
            app.ThreshOutputImage = im2bw(app.ThreshInputImage,value)
           
           
            imshow(app.ThreshOutputImage)
             
        end

        % Button pushed function: BinarizeSave
        function BinarizeSaveButtonPushed(app, event)
            ind1 = strfind(app.ThreshInputFilename,'.')
            ind1 = ind1(end) - 1
            
            FILENAME = strcat(app.ThreshInputFilename(1:ind1),'_binary.jpg')
            imwrite(app.ThreshOutputImage,FILENAME)
           
        end

        % Button pushed function: MorphSave
        function MorphSaveButtonPushed(app, event)
        %FILENAME = strcat(app.orig_filename(1:size(app.orig_filename,2)-4),'_morph.jpg')
        
        ind = strfind(app.orig_filename,'.')
        ind = ind(end) - 1
        
        FILENAME = strcat(app.orig_filename(1:ind),'_morph.jpg')
        
        imwrite(app.morph_img,FILENAME)
        end

        % Button pushed function: EdgeOpen
        function EdgeOpenButtonPushed(app, event)
            
            [app.EdgeInputFilename,filepath] = uigetfile
            app.EdgeInputImage = imread(app.EdgeInputFilename)
            figure('Name','Input for Edge detection'),imshow(app.EdgeInputImage)
        end

        % Button pushed function: EdgeTest
        function EdgeTestButtonPushed(app, event)
            
            t = app.EdgeSlider.Value
            dir = app.EdgeDir.Value
            sigma = app.EdgeSigma.Value
            img = app.EdgeInputImage
            edge_type =app.EdgeType.Value
            
            
            if strcmp(edge_type,'Sobel')
                app.EdgeOutputImage = edge(img,'sobel',t,dir)
            elseif strcmp(edge_type,'Prewitt')
                app.EdgeOutputImage = edge(img,'prewitt',t,dir)
            elseif strcmp(edge_type,'LOG')
                app.EdgeOutputImage = edge(img,'log',t,sigma)
            elseif strcmp(edge_type,'0-cross')
                app.EdgeOutputImage = edge(img,'zerocross',t,sigma)
            elseif strcmp(edge_type,'Canny')
                app.EdgeOutputImage = edge(img,'canny',t,sigma)
            end
            
            imshow(app.EdgeOutputImage)
        end

        % Button pushed function: EdgeSave
        function EdgeSaveButtonPushed(app, event)
         
        if app.EdgeCustomName.Value == 0
            ind = strfind(app.EdgeInputFilename,'.')
            ind = ind(end) - 1
            FILENAME = strcat(app.EdgeInputFilename(1:ind),'_edge.jpg')
            imwrite(app.EdgeOutputImage,FILENAME)
            
            
        elseif app.EdgeCustomName.Value == 1
            inputfile_format = app.EdgeInputFilename(strfind(app.EdgeInputFilename,'.'):end)
            output_filename = strcat(app.EdgeOutputName.Value,inputfile_format)
            imwrite(app.EdgeOutputImage,output_filename)
            
        end
            
        
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Morphology';
            app.Tab.Units = 'pixels';
            app.MorphOpen = uibutton(app.Tab, 'push');
            app.MorphOpen.ButtonPushedFcn = createCallbackFcn(app, @MorphOpenButtonPushed, true);
            app.MorphOpen.Icon = 'circle.jpg';
            app.MorphOpen.Text = 'Open Image';
            app.MorphTest = uibutton(app.Tab, 'push');
            app.MorphTest.ButtonPushedFcn = createCallbackFcn(app, @MorphTestButtonPushed, true);
            app.MorphTest.Text = 'Test';
            app.LabelDropDown = uilabel(app.Tab);
            app.LabelDropDown.Text = 'SE Type';
            app.MorphStrelType = uidropdown(app.Tab);
            app.MorphStrelType.Items = {'diamond', 'disk', 'line', 'octagon', 'rectangle', 'square', 'cube', 'cuboid', 'sphere'};
            app.MorphStrelType.Value = 'diamond';
            app.LabelDropDown2 = uilabel(app.Tab);
            app.LabelDropDown2.Text = 'Type';
            app.MorphType = uidropdown(app.Tab);
            app.MorphType.Items = {'Erosion', 'Dilation'};
            app.MorphType.Value = 'Erosion';
            app.LabelSlider = uilabel(app.Tab);
            app.LabelSlider.Text = '';
            app.MorphSlider = uislider(app.Tab);
            app.MorphSlider.ValueChangingFcn = createCallbackFcn(app, @MorphSliderValueChanging, true);
            app.MorphSave = uibutton(app.Tab, 'push');
            app.MorphSave.ButtonPushedFcn = createCallbackFcn(app, @MorphSaveButtonPushed, true);
            app.MorphSave.Text = 'Save';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Binarize';
            app.Tab2.Units = 'pixels';
            app.LabelSlider2 = uilabel(app.Tab2);
            app.LabelSlider2.Text = 'Threshold';
            app.BinarizeSlider = uislider(app.Tab2);
            app.BinarizeSlider.Limits = [0 1];
            app.BinarizeSlider.ValueChangedFcn = createCallbackFcn(app, @BinarizeSliderValueChanged, true);
            app.BinarizeTest = uibutton(app.Tab2, 'push');
            app.BinarizeTest.ButtonPushedFcn = createCallbackFcn(app, @BinarizeTestButtonPushed, true);
            app.BinarizeTest.Text = 'Test';
            app.BinarizeOpen = uibutton(app.Tab2, 'push');
            app.BinarizeOpen.ButtonPushedFcn = createCallbackFcn(app, @BinarizeOpenButtonPushed, true);
            app.BinarizeOpen.Icon = 'circle.jpg';
            app.BinarizeOpen.Text = 'Open Image';
            app.BinarizeSave = uibutton(app.Tab2, 'push');
            app.BinarizeSave.ButtonPushedFcn = createCallbackFcn(app, @BinarizeSaveButtonPushed, true);
            app.BinarizeSave.Text = 'Save';
            app.EdgesTab = uitab(app.TabGroup);
            app.EdgesTab.Title = 'Edges';
            app.EdgesTab.Units = 'pixels';
            app.TypeDropDownLabel = uilabel(app.EdgesTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.EdgeType = uidropdown(app.EdgesTab);
            app.EdgeType.Items = {'Sobel', 'Prewitt', 'LOG', '0-cross', 'Canny'};
            app.EdgeType.Value = 'Sobel';
            app.EdgeOpen = uibutton(app.EdgesTab, 'push');
            app.EdgeOpen.ButtonPushedFcn = createCallbackFcn(app, @EdgeOpenButtonPushed, true);
            app.EdgeOpen.Icon = 'circle.jpg';
            app.EdgeOpen.Text = 'Open Image';
            app.ThresholdSliderLabel = uilabel(app.EdgesTab);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.EdgeSlider = uislider(app.EdgesTab);
            app.EdgeSlider.Limits = [0 1];
            app.EdgeSlider.Value = 1;
            app.SigmaHocrossLabel = uilabel(app.EdgesTab);
            app.SigmaHocrossLabel.Text = 'Sigma/H(o-cross)';
            app.EdgeSigma = uieditfield(app.EdgesTab, 'numeric');
            app.EdgeSigma.Value = 1;
            app.EdgeTest = uibutton(app.EdgesTab, 'push');
            app.EdgeTest.ButtonPushedFcn = createCallbackFcn(app, @EdgeTestButtonPushed, true);
            app.EdgeTest.Text = 'Test';
            app.EdgeSave = uibutton(app.EdgesTab, 'push');
            app.EdgeSave.ButtonPushedFcn = createCallbackFcn(app, @EdgeSaveButtonPushed, true);
            app.EdgeSave.Text = 'Save';
            app.DirectionDropDownLabel = uilabel(app.EdgesTab);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.EdgeDir = uidropdown(app.EdgesTab);
            app.EdgeDir.Items = {'Horizontal', 'Vertical', 'Option 3', 'Option 4'};
            app.EdgeDir.Value = 'Horizontal';
            app.EdgeCustomName = uicheckbox(app.EdgesTab);
            app.EdgeCustomName.Text = 'Custom Name';
            app.EditFieldLabel = uilabel(app.EdgesTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EdgeOutputName = uieditfield(app.EdgesTab, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end